classdef app10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        EstadoLamp         matlab.ui.control.Lamp
        EstadoLampLabel    matlab.ui.control.Label
        DesconectarButton  matlab.ui.control.Button
        ConectarButton     matlab.ui.control.Button
        ZSpinnerLabel      matlab.ui.control.Label
        ZKnobLabel         matlab.ui.control.Label
        XKnob              matlab.ui.control.Knob
        XSpinner           matlab.ui.control.Spinner
        YKnob              matlab.ui.control.Knob
        YSpinner           matlab.ui.control.Spinner
        ZKnob              matlab.ui.control.Knob
        ZSpinner           matlab.ui.control.Spinner
        YSpinnerLabel      matlab.ui.control.Label
        YKnobLabel         matlab.ui.control.Label
        XKnobLabel         matlab.ui.control.Label
        XSpinnerLabel      matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        matrizR = [];
        matrizVieja = [];
        matrizNueva = [];

        ultimaRotacion = '';

        actualAngulo_X;
        actualAngulo_Y;
        actualAngulo_Z;
        ultimoAngulo_X;
        ultimoAngulo_Y;
        ultimoAngulo_Z;

        % --- Ploteo de Ejes Fijos ---  .
        largoDeEjes = [-1.25 1.25];

        %---- Comuncacion serial -------.
        comunicacionSerial;
        estadoConexion;
        dataRecibida;
        valueToNum = 0.00;
        adaptedNum = 0.00;
        modalS;
    end
    
    methods (Access = private)
        
        function ploteoEjesFijos(app)
            line(app.UIAxes,app.largoDeEjes,[0 0],[0 0],'color','#A9A9A9','linewidth',1);
            line(app.UIAxes, [0 0],app.largoDeEjes,[0 0],'color','#A9A9A9','linewidth',1)
            line(app.UIAxes,[0 0],[0 0],app.largoDeEjes,'color','#A9A9A9','linewidth',1);
        end

        function ploteoDeTriada(app)
            line(app.UIAxes,[0 app.matrizR(1,1)],[0 app.matrizR(2,1)],[0 app.matrizR(3,1)],'color','r','linewidth',3); %Eje de U  
            line(app.UIAxes,[0 app.matrizR(1,2)],[0 app.matrizR(2,2)],[0 app.matrizR(3,2)],'color','g','linewidth',3); %Eje de V 
            line(app.UIAxes,[0 app.matrizR(1,3)],[0 app.matrizR(2,3)],[0 app.matrizR(3,3)],'color','b','linewidth',3); %Eje de W 
        end

        function [RotX] = rotacionEnX(app,anguloA)
            xU = 1;
            xV = 0;
            xW = 0;
            yU = 0;
            yV = cosd(anguloA);
            yW = -sind(anguloA);
            zU = 0;
            zV = sind(anguloA);
            zW = cosd(anguloA);

            RotX = [xU,xV,xW,0;yU,yV,yW,0;zU,zV,zW,0;0, 0, 0,1]; 
        end

        function [RotY] = rotacionEnY(app,anguloB)
            xU = cosd(anguloB);
            xV = 0;
            xW = sind(anguloB);
            yU = 0;
            yV = 1;
            yW = 0;
            zU = -sind(anguloB);
            zV = 0;
            zW = cosd(anguloB);

            RotY = [xU,xV,xW,0;yU,yV,yW,0;zU,zV,zW,0;0,0,0,1];
        end

        function [RotZ] = rotacionEnZ(app,anguloC)
            xU = cosd(anguloC);
            xV = -sind(anguloC);
            xW = 0;
            yU = sind(anguloC);
            yV = cosd(anguloC);
            yW = 0;
            zU = 0;
            zV = 0;
            zW = 1;

            RotZ = [xU,xV,xW,0;yU,yV,yW,0;zU,zV,zW,0;0,0,0,1];
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.ultimoAngulo_X = 0;
            app.ultimoAngulo_Y = 0;
            app.ultimoAngulo_Z = 0;


            app.matrizVieja=[1,0,0,0;0,1,0,0;0,0,1,0;0,0,0,1];
            app.matrizR=[1,0,0,0;0,1,0,0;0,0,1,0;0,0,0,1];

            % --- ploteo --- .
            cla(app.UIAxes);
            ploteoEjesFijos(app);
            ploteoDeTriada(app);

            % --- serial --- .
            app.estadoConexion = "desconectado";
        end

        % Value changing function: XKnob
        function XKnobValueChanging(app, event)
            changingValue = event.Value;
            app.XSpinner.Value = changingValue;

        end

        % Value changed function: XKnob
        function XKnobValueChanged(app, event)
            changingValue = app.XKnob.Value;

            if app.ultimaRotacion ~= 'x'
                app.matrizVieja = app.matrizR;
            end

            app.ultimaRotacion = 'x';

            app.ultimoAngulo_Y = app.YKnob.Value;
            app.ultimoAngulo_Z = app.ZKnob.Value;

            if changingValue == 0
                app.actualAngulo_X = -app.ultimoAngulo_X;
            elseif app.ultimoAngulo_X ~= changingValue
                app.actualAngulo_X = changingValue - app.ultimoAngulo_X;
            end

            % --- Rotaciones y matrices --- .
            app.matrizNueva = rotacionEnX(app,app.actualAngulo_X);
            app.matrizR = app.matrizNueva * app.matrizVieja;

            % --- ploteo --- .
            cla(app.UIAxes);
            ploteoEjesFijos(app);
            ploteoDeTriada(app);
        end

        % Value changing function: XSpinner
        function XSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.XKnob.Value = changingValue;

            if app.ultimaRotacion ~= 'x'
                app.matrizVieja = app.matrizR;
            end

            app.ultimaRotacion = 'x';

            app.ultimoAngulo_Y = app.YKnob.Value;
            app.ultimoAngulo_Z = app.ZKnob.Value;

            if changingValue == 0
                app.actualAngulo_X = -app.ultimoAngulo_X;
            elseif app.ultimoAngulo_X ~= changingValue
                app.actualAngulo_X = changingValue - app.ultimoAngulo_X;
            end

            % --- Rotaciones y matrices --- .
            app.matrizNueva = rotacionEnX(app,app.actualAngulo_X);
            app.matrizR = app.matrizNueva * app.matrizVieja;

            % --- ploteo --- .
            cla(app.UIAxes);
            ploteoEjesFijos(app);
            ploteoDeTriada(app);
        end

        % Value changing function: YKnob
        function YKnobValueChanging(app, event)
            changingValue = event.Value;
            app.YSpinner.Value = changingValue;

            if app.ultimaRotacion ~= 'y'
                app.matrizVieja = app.matrizR;
            end

            app.ultimaRotacion = 'y';

            app.ultimoAngulo_X = app.XKnob.Value;
            app.ultimoAngulo_Z = app.ZKnob.Value;

            if changingValue == 0
                app.actualAngulo_Y = -app.ultimoAngulo_Y;
            elseif app.ultimoAngulo_Y ~= changingValue
                app.actualAngulo_Y = changingValue - app.ultimoAngulo_Y;
            end

            % --- Rotaciones y matrices --- .
            app.matrizNueva = rotacionEnY(app,app.actualAngulo_Y);
            app.matrizR = app.matrizNueva * app.matrizVieja;


            % --- ploteo --- .
            cla(app.UIAxes);
            ploteoEjesFijos(app);
            ploteoDeTriada(app);
        end

        % Value changing function: YSpinner
        function YSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.YKnob.Value = changingValue;

            if app.ultimaRotacion ~= 'y'
                app.matrizVieja = app.matrizR;
            end

            app.ultimaRotacion = 'y';

            app.ultimoAngulo_X = app.XKnob.Value;
            app.ultimoAngulo_Z = app.ZKnob.Value;

            if changingValue == 0
                app.actualAngulo_Y = -app.ultimoAngulo_Y;
            elseif app.ultimoAngulo_Y ~= changingValue
                app.actualAngulo_Y = changingValue - app.ultimoAngulo_Y;
            end

            % --- Rotaciones y matrices --- .
            app.matrizNueva = rotacionEnY(app,app.actualAngulo_Y);
            app.matrizR = app.matrizNueva * app.matrizVieja;


            % --- ploteo --- .
            cla(app.UIAxes);
            ploteoEjesFijos(app);
            ploteoDeTriada(app);
        end

        % Value changing function: ZKnob
        function ZKnobValueChanging(app, event)
            changingValue = event.Value;
            app.ZSpinner.Value = changingValue;


            if app.ultimaRotacion ~= 'z'
                app.matrizVieja = app.matrizR;
            end

            app.ultimaRotacion = 'z';

            app.ultimoAngulo_Y = app.YKnob.Value;
            app.ultimoAngulo_X = app.XKnob.Value;

            if changingValue == 0
                app.actualAngulo_Z = -app.ultimoAngulo_Z;
            elseif app.ultimoAngulo_Z ~= changingValue
                app.actualAngulo_Z = changingValue - app.ultimoAngulo_Z;
            end

            % --- Rotaciones y matrices --- .
            app.matrizNueva = rotacionEnZ(app,app.actualAngulo_Z);
            app.matrizR = app.matrizNueva * app.matrizVieja;


            % --- ploteo --- .
            cla(app.UIAxes);
            ploteoEjesFijos(app);
            ploteoDeTriada(app);
        end

        % Value changing function: ZSpinner
        function ZSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.ZKnob.Value = changingValue;

            if app.ultimaRotacion ~= 'z'
                app.matrizVieja = app.matrizR;
            end

            app.ultimaRotacion = 'z';

            app.ultimoAngulo_Y = app.YKnob.Value;
            app.ultimoAngulo_X = app.XKnob.Value;

            if changingValue == 0
                app.actualAngulo_Z = -app.ultimoAngulo_Z;
            elseif app.ultimoAngulo_Z ~= changingValue
                app.actualAngulo_Z = changingValue - app.ultimoAngulo_Z;
            end

            % --- Rotaciones y matrices --- .
            app.matrizNueva = rotacionEnZ(app,app.actualAngulo_Z);
            app.matrizR = app.matrizNueva * app.matrizVieja;


            % --- ploteo --- .
            cla(app.UIAxes);
            ploteoEjesFijos(app);
            ploteoDeTriada(app);
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            %iniciar la conexión serial
            app.comunicacionSerial = [];
            pause(0.25);
            app.comunicacionSerial= serialport("COM7",115200);
            configureTerminator(app.comunicacionSerial,"CR/LF");
            flush(app.comunicacionSerial);

            app.EstadoLamp.Color = "green";
            app.XKnob.Enable ="off";
            app.XSpinner.Enable = "off";
            app.estadoConexion = "conectado";

            while(app.estadoConexion == "conectado")
                writeline(app.comunicacionSerial,'s');
                pause(0.1);
                app.dataRecibida = readline(app.comunicacionSerial);
                app.valueToNum = str2double(app.dataRecibida);
                app.adaptedNum = app.valueToNum - 180;
                app.XSpinner.Value = app.adaptedNum;
                app.XKnob.Value = app.adaptedNum;

                XKnobValueChanged(app);
            end

        end

        % Button pushed function: DesconectarButton
        function DesconectarButtonPushed(app, event)
           app.estadoConexion = "desconectado";
           clear comunicacionSerial;
           app.EstadoLamp.Color = "Red";
           app.XKnob.Enable ="on";
           app.XSpinner.Enable = "on";
           app.modalS = msgbox("Puerto serial desconectado","Succes","warn");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'triada')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [-3 3];
            app.UIAxes.ZLim = [-3 3];
            app.UIAxes.XTick = [-2 0 2];
            app.UIAxes.XTickLabel = {'-2'; '0'; '2'};
            app.UIAxes.YTick = [-3 -2 -1 0 1 2 3];
            app.UIAxes.YTickLabel = {'-3'; '-2'; '-1'; '0'; '1'; '2'; '3'};
            app.XSpinnerLabel = uilabel(app.UIFigure);
            app.XSpinnerLabel.Text = 'X';
            app.XKnobLabel = uilabel(app.UIFigure);
            app.XKnobLabel.Text = 'X';
            app.YKnobLabel = uilabel(app.UIFigure);
            app.YKnobLabel.Text = 'Y';
            app.YSpinnerLabel = uilabel(app.UIFigure);
            app.YSpinnerLabel.Text = 'Y';
            app.ZSpinner = uispinner(app.UIFigure);
            app.ZSpinner.ValueChangingFcn = createCallbackFcn(app, @ZSpinnerValueChanging, true);
            app.ZSpinner.Limits = [-90 90];
            app.ZKnob = uiknob(app.UIFigure, 'continuous');
            app.ZKnob.Limits = [-90 90];
            app.ZKnob.MajorTicks = [-90 -75 -60 -45 -30 -15 0 15 30 45 60 75 90];
            app.ZKnob.ValueChangingFcn = createCallbackFcn(app, @ZKnobValueChanging, true);
            app.YSpinner = uispinner(app.UIFigure);
            app.YSpinner.ValueChangingFcn = createCallbackFcn(app, @YSpinnerValueChanging, true);
            app.YSpinner.Limits = [-90 90];
            app.YKnob = uiknob(app.UIFigure, 'continuous');
            app.YKnob.Limits = [-90 90];
            app.YKnob.MajorTicks = [-90 -75 -60 -45 -30 -15 0 15 30 45 60 75 90];
            app.YKnob.ValueChangingFcn = createCallbackFcn(app, @YKnobValueChanging, true);
            app.XSpinner = uispinner(app.UIFigure);
            app.XSpinner.ValueChangingFcn = createCallbackFcn(app, @XSpinnerValueChanging, true);
            app.XSpinner.Limits = [-190 190];
            app.XKnob = uiknob(app.UIFigure, 'continuous');
            app.XKnob.Limits = [-190 190];
            app.XKnob.MajorTicks = [-190 -152 -114 -76 -38 0 38 76 114 152 190];
            app.XKnob.ValueChangedFcn = createCallbackFcn(app, @XKnobValueChanged, true);
            app.XKnob.ValueChangingFcn = createCallbackFcn(app, @XKnobValueChanging, true);
            app.ZKnobLabel = uilabel(app.UIFigure);
            app.ZKnobLabel.Text = 'Z';
            app.ZSpinnerLabel = uilabel(app.UIFigure);
            app.ZSpinnerLabel.Text = 'Z';
            app.ConectarButton = uibutton(app.UIFigure, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.DesconectarButton = uibutton(app.UIFigure, 'push');
            app.DesconectarButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarButtonPushed, true);
            app.DesconectarButton.Text = 'Desconectar';
            app.EstadoLampLabel = uilabel(app.UIFigure);
            app.EstadoLampLabel.Text = 'Estado';
            app.EstadoLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app10

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end